classdef CovidApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Covid19VisualizationUIFigure  matlab.ui.Figure
        StateorRegionListBox          matlab.ui.control.ListBox
        StateorRegionListBoxLabel     matlab.ui.control.Label
        CountriesListBox              matlab.ui.control.ListBox
        CountriesListBoxLabel         matlab.ui.control.Label
        AveragedofdaysSlider          matlab.ui.control.Slider
        AveragedofdaysSliderLabel     matlab.ui.control.Label
        OptionButtonGroup             matlab.ui.container.ButtonGroup
        DailyButton                   matlab.ui.control.RadioButton
        CumulativeButton              matlab.ui.control.RadioButton
        DatatoPlotButtonGroup         matlab.ui.container.ButtonGroup
        BothButton                    matlab.ui.control.RadioButton
        DeathsButton                  matlab.ui.control.RadioButton
        CasesButton                   matlab.ui.control.RadioButton
        UIAxis                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        everything      % All Data
        ShowCases       % Covid Cases
        ShowDeaths      % Deaths
        DateMonth       % xaxis
        ShowBoth        % Both
        ShowCumulative  % Cumulative Data
        ShowDaily       % Daily Data
        DailyDataCases  % Daily Cases Calculated 
        DailyDataDeaths % Daily Deaths Calculated
        Averagewindow   % Size of avg window
        index           % Index value
        index2          % Second Index
        Name            % Title
    end
    
    methods (Access = private)
        %plots of cumulative for the data according to the selection menu
        function plotData(app)
            cla(app.UIAxis,"reset");
            CumuData = {app.everything{app.index2,3:end}};
            %for if cases option selected
                if app.ShowCases
                    applycases = cellfun(@(var)var(1),CumuData);
                    bar(app.UIAxis,app.DateMonth,movmean(applycases,[app.Averagewindow,0]));
                    title(app.UIAxis,['Cumulative Number of Cases in',' ',app.Name])
                end
            %for if deaths option selected
                if app.ShowDeaths
                    applydeaths = cellfun(@(v)v(2),CumuData);
                    plot(app.UIAxis,app.DateMonth,movmean(applydeaths,[app.Averagewindow,0]),"red");
                    title(app.UIAxis,['Cumulative Number of Deaths in',' ',app.Name])
                end
            %for if both option selected
                if app.ShowBoth
                    applyboth = cellfun(@(v)v(1),CumuData);
                    bar(app.UIAxis,app.DateMonth,movmean(applyboth,[app.Averagewindow,0]));
                    hold(app.UIAxis,"on")
                    yyaxis(app.UIAxis,"right")
                    applyboth = cellfun(@(v)v(2),CumuData);
                    plot(app.UIAxis,app.DateMonth,movmean(applyboth,[app.Averagewindow,0]),"red");
                    title(app.UIAxis,['Cumulative Number of Cases and Deaths in',' ',app.Name])
                end
                hold(app.UIAxis,"off")
                xlabel(app.UIAxis,'month/day')
                xticks(app.UIAxis,app.DateMonth(1:30:end));
                app.UIAxis.XTickLabel = {'01/01'; '02/01'; '03/01'; '04/01'; '05/01'; '06/01'; '07/01'; '08/01'; '09/01'; '10/01'; '11/01'; '12/01'};
                grid(app.UIAxis,'on')
        end
        function plotDaily(app)
        %plots for daily option selected in combination with selection menu
            cla(app.UIAxis,"reset");
            %for cases option
            if app.ShowCases
                bar(app.UIAxis,app.DateMonth,movmean(app.DailyDataCases,[app.Averagewindow,0]));
                   title(app.UIAxis,['Daily Number of Cases in',' ',app.Name])
            end
            %for deaths option
            if app.ShowDeaths
                plot(app.UIAxis,app.DateMonth,movmean(app.DailyDataDeaths,[app.Averagewindow,0]),"red");
                title(app.UIAxis,['Daily Number of Deaths in',' ',app.Name])
            end
            %for both option
            if app.ShowBoth
                bar(app.UIAxis,app.DateMonth,movmean(app.DailyDataCases,[app.Averagewindow,0]));
                hold(app.UIAxis,"on")
                yyaxis(app.UIAxis,"right")
                plot(app.UIAxis,app.DateMonth,movmean(app.DailyDataDeaths,[app.Averagewindow,0]),"red");
                title(app.UIAxis,['Daily Number of Cases and Deaths in',' ',app.Name])
            end
            hold(app.UIAxis,"off")
            xlabel(app.UIAxis,'month/day')
            xticks(app.UIAxis,app.DateMonth(1:30:end))
            app.UIAxis.XTickLabel = {'01/01'; '02/01'; '03/01'; '04/01'; '05/01'; '06/01'; '07/01'; '08/01'; '09/01'; '10/01'; '11/01'; '12/01'};
            grid(app.UIAxis,'on')
        end
        function Daily(app)
            %daily count, same as (dailycount.m)
            CumuData = {app.everything{app.index2,3:end}};
            c = cellfun(@(v)v(1),CumuData);
            d = cellfun(@(v)v(2),CumuData);
            a = zeros(1,375);
            b = zeros(1,375);
            a(1) = c(1);
            b(1) = d(1);
            for i = 2:length(c)
                a(i)=c(i)-c(i-1);
                b(i)=d(i)-d(i-1);
                if a(i)<0
                    a(i) = 0;
                end
                if b(i)<0
                    b(i)=0;
                end    
            end
            app.DailyDataCases = a;
            app.DailyDataDeaths = b;
        end
        function plotAll(app)
            %plot if no country selected and cumulative
            cla(app.UIAxis,"reset");
            [s,v]=cumulativeCount();
                %for case option
                if app.ShowCases
                    bar(app.UIAxis,app.DateMonth,movmean(s,[app.Averagewindow,0]));
                    title(app.UIAxis,'Cumulative Number of Cases Globally')
                end
                %for deaths option
                if app.ShowDeaths
                    plot(app.UIAxis,app.DateMonth,movmean(v,[app.Averagewindow,0]),"red");
                    title(app.UIAxis,'Cumulative Number of Deaths Globally')
                end
                %for both option
                if app.ShowBoth
                    bar(app.UIAxis,app.DateMonth,movmean(s,[app.Averagewindow,0]));
                    hold(app.UIAxis,"on")
                    yyaxis(app.UIAxis,"right")
                    plot(app.UIAxis,app.DateMonth,movmean(v,[app.Averagewindow,0]),"red");
                    title(app.UIAxis,'Cumulative Number of Cases and Deaths Globally')
                end
                hold(app.UIAxis,"off")
                xlabel(app.UIAxis,'month/day')
                xticks(app.UIAxis,app.DateMonth(1:30:end));
                app.UIAxis.XTickLabel = {'01/01'; '02/01'; '03/01'; '04/01'; '05/01'; '06/01'; '07/01'; '08/01'; '09/01'; '10/01'; '11/01'; '12/01'};
                grid(app.UIAxis,'on')  
        end
        function plotDailyAll(app) 
            %plot if no country selected and daily
            cla(app.UIAxis,"reset");
            [s,v]=cumulativeCount();
            [a,b]=dailyCount(s,v);
                %for cases option
                if app.ShowCases
                    bar(app.UIAxis,app.DateMonth,movmean(a,[app.Averagewindow,0]));
                    title(app.UIAxis,'Daily Number of Cases Globally')
                end
                %for deaths option
                if app.ShowDeaths
                    plot(app.UIAxis,app.DateMonth,movmean(b,[app.Averagewindow,0]),"red");
                    title(app.UIAxis,'Daily Number of Deaths Globally')
                end
                %for both option
                if app.ShowBoth
                    bar(app.UIAxis,app.DateMonth,movmean(a,[app.Averagewindow,0]));
                    hold(app.UIAxis,"on")
                    yyaxis(app.UIAxis,"right")
                    plot(app.UIAxis,app.DateMonth,movmean(b,[app.Averagewindow,0]),"red");
                    title(app.UIAxis,'Daily Number of Cases and Deaths Globally')
                end
                hold(app.UIAxis,"off")
                xlabel(app.UIAxis,'month/day')
                xticks(app.UIAxis,app.DateMonth(1:30:end));
                app.UIAxis.XTickLabel = {'01/01'; '02/01'; '03/01'; '04/01'; '05/01'; '06/01'; '07/01'; '08/01'; '09/01'; '10/01'; '11/01'; '12/01'};
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load covid_data.mat covid_data;
            app.everything = covid_data;
            s = {app.everything{1,3:end}};
            %loads data and loads in button groups
            app.DateMonth = datetime(s);
            app.ShowCases = app.CasesButton.Value;
            app.ShowDeaths = app.DeathsButton.Value;
            app.ShowBoth =  app.BothButton.Value;
            app.ShowCumulative = app.CumulativeButton.Value;
            app.ShowDaily = app.DailyButton.Value;
            app.Averagewindow = app.AveragedofdaysSlider.Value; 
            u = unique({app.everything{2:end,1}});
            %loads in countries and states if apply into lists
            app.CountriesListBox.Items = {'All',u{1:1:end}}; 
            app.CountriesListBox.Value = 'All';
            app.index=find(contains(app.CountriesListBox.Items,app.CountriesListBox.Value));
            app.StateorRegionListBox.Items = {'All'};
            app.index2 = find(contains(app.everything(1:end,1),app.CountriesListBox.Value));
            %plot
            app.plotDailyAll();
            app.plotAll()
          
        end

        % Callback function
        function DatatoPlotButtonGroupSelectionChanged(app, event)
     
        end

        % Callback function
        function DatatoPlotButtonGroupButtonDown(app, event)
            
        end

        % Callback function: AveragedofdaysSlider, DatatoPlotButtonGroup, 
        % ...and 2 other components
        function OptionButtonGroupSelectionChanged(app, event)
            if strcmp(app.CountriesListBox.Value,'All')
                app.StateorRegionListBox.Items = {'All'};
                app.Averagewindow = round(app.AveragedofdaysSlider.Value);
                app.ShowCumulative = app.CumulativeButton.Value;
                app.ShowDaily = app.DailyButton.Value;
                %cumulative button group selection route, no choice, day
                %slider
                if app.ShowCumulative
                    app.ShowCases = app.CasesButton.Value;
                    app.ShowDeaths = app.DeathsButton.Value;
                    app.ShowBoth = app.BothButton.Value;
                    app.plotAll()
                end
                %daily button group selection route, no choice, day slider
                if app.ShowDaily
                    app.ShowCases = app.CasesButton.Value;
                    app.ShowDeaths = app.DeathsButton.Value;
                    app.ShowBoth = app.BothButton.Value;
                    app.plotDailyAll()
                end
            else
             app.index=find(contains(app.everything(1:end,1),app.CountriesListBox.Value));
             u = {app.everything{app.index(1):app.index(length(app.index)),2}};
             u{1,1}='All';
             %if no states listed as "All"
             if length(app.index)==1
                    app.StateorRegionListBox.Items = {'All'};
                     app.index2 = find(contains(app.everything(1:end,1),app.CountriesListBox.Value));
                     app.Name = app.CountriesListBox.Value;
             else
             %list states
                    app.StateorRegionListBox.Items = u;
                    if strcmp(app.StateorRegionListBox.Value,'All')
                        app.index2 = app.index(1);
                        app.Name = app.CountriesListBox.Value;
                    else
                        app.index2 = find(contains(app.everything(1:end,2),app.StateorRegionListBox.Value));
                        app.Name = app.StateorRegionListBox.Value;
                    end    
             end
             app.Averagewindow = round(app.AveragedofdaysSlider.Value);
             app.ShowCumulative = app.CumulativeButton.Value;
             app.ShowDaily = app.DailyButton.Value;
             app.Daily()
            %cumulative button group selection route, made choice
            if app.ShowCumulative
                app.ShowCases = app.CasesButton.Value;
                app.ShowDeaths = app.DeathsButton.Value;
                app.ShowBoth = app.BothButton.Value;
                app.plotData()
            end
            %daily button group selection route, no choice
            if app.ShowDaily
                app.ShowCases = app.CasesButton.Value;
                app.ShowDeaths = app.DeathsButton.Value;
                app.ShowBoth = app.BothButton.Value;
                app.plotDaily()
            end
            end
            
        end

        % Callback function
        function AveragedofdaysSliderValueChanged(app, event)

        end

        % Callback function
        function ListBoxValueChanged(app, event)
       
        end

        % Value changed function: CountriesListBox
        function StateorRegionListBoxValueChanged(app, event)
            if strcmp(app.CountriesListBox.Value,'All')
                app.StateorRegionListBox.Items = {'All'};
                app.Averagewindow = round(app.AveragedofdaysSlider.Value);
                app.ShowCumulative = app.CumulativeButton.Value;
                app.ShowDaily = app.DailyButton.Value;
                %cumulative button selection, all countries
                if app.ShowCumulative
                    app.ShowCases = app.CasesButton.Value;
                    app.ShowDeaths = app.DeathsButton.Value;
                    app.ShowBoth = app.BothButton.Value;
                    app.plotAll()
                end
                %daily button selection, all countries
                if app.ShowDaily
                    app.ShowCases = app.CasesButton.Value;
                    app.ShowDeaths = app.DeathsButton.Value;
                    app.ShowBoth = app.BothButton.Value;
                    app.plotDailyAll()
                end
            else
                app.index=find(contains(app.everything(1:end,1),app.CountriesListBox.Value));
                u = {app.everything{app.index(1):app.index(length(app.index)),2}};
                u{1,1}='All';
                %lists all if no states in country
                if length(app.index)==1
                    app.StateorRegionListBox.Items = {'All'};
                     app.index2 = find(contains(app.everything(1:end,1),app.CountriesListBox.Value));
                     app.Name = app.CountriesListBox.Value;
                else
                %lists the states/regions in the country
                    app.StateorRegionListBox.Items = u;
                    if strcmp(app.StateorRegionListBox.Value,'All')
                        app.index2 = app.index(1);
                        app.Name = app.CountriesListBox.Value;
                    else
                        app.index2 = find(contains(app.everything(1:end,2),app.StateorRegionListBox.Value));
                        app.Name = app.StateorRegionListBox.Value;
                    end
                end
                app.Averagewindow = round(app.AveragedofdaysSlider.Value);
                app.ShowCumulative = app.CumulativeButton.Value;
                app.ShowDaily = app.DailyButton.Value;
                app.Daily()
                %plot after button choice, cumulative
                if app.ShowCumulative
                    app.ShowCases = app.CasesButton.Value;
                    app.ShowDeaths = app.DeathsButton.Value;
                    app.ShowBoth = app.BothButton.Value;
                    app.plotData()
                end
                %plots after button choice, daily
                if app.ShowDaily
                    app.ShowCases = app.CasesButton.Value;
                    app.ShowDeaths = app.DeathsButton.Value;
                    app.ShowBoth = app.BothButton.Value;
                    app.plotDaily()
        
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Covid19VisualizationUIFigure = uifigure('Visible', 'off');
            app.Covid19VisualizationUIFigure.Name = 'Covid-19 Data Visualization (Data Source: Johns Hopkins University) ';
            app.UIAxis = uiaxes(app.Covid19VisualizationUIFigure);
            title(app.UIAxis, 'Title')
            xlabel(app.UIAxis, 'month/day')
            ylabel(app.UIAxis, 'count')
            zlabel(app.UIAxis, 'Z')
            app.DatatoPlotButtonGroup = uibuttongroup(app.Covid19VisualizationUIFigure);
            app.DatatoPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.DatatoPlotButtonGroup.Title = 'Data to Plot';
            app.CasesButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.DeathsButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.BothButton.Value = true;
            app.OptionButtonGroup = uibuttongroup(app.Covid19VisualizationUIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.AveragedofdaysSliderLabel = uilabel(app.Covid19VisualizationUIFigure);
            app.AveragedofdaysSliderLabel.Text = {'Averaged #'; 'of days'};
            app.AveragedofdaysSlider = uislider(app.Covid19VisualizationUIFigure);
            app.AveragedofdaysSlider.Limits = [1 15];
            app.AveragedofdaysSlider.ValueChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.AveragedofdaysSlider.Value = 1;
            app.CountriesListBoxLabel = uilabel(app.Covid19VisualizationUIFigure);
            app.CountriesListBoxLabel.Text = 'Countries:';
            app.CountriesListBox = uilistbox(app.Covid19VisualizationUIFigure);
            app.CountriesListBox.ValueChangedFcn = createCallbackFcn(app, @StateorRegionListBoxValueChanged, true);
            app.CountriesListBox.Value = 'Item 3';
            app.StateorRegionListBoxLabel = uilabel(app.Covid19VisualizationUIFigure);
            app.StateorRegionListBoxLabel.Text = {'State'; 'or Region :'};
            app.StateorRegionListBox = uilistbox(app.Covid19VisualizationUIFigure);
            app.StateorRegionListBox.ValueChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            % Show the figure after all components are created
            app.Covid19VisualizationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CovidApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Covid19VisualizationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Covid19VisualizationUIFigure)
        end
    end
end